classdef MonteCarloPi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonteCarloPiUIFigure            matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        NumberofpointsEditField         matlab.ui.control.NumericEditField
        PiapproxEditFieldLabel          matlab.ui.control.Label
        PiapproxEditField               matlab.ui.control.NumericEditField
        PlotpointsButton                matlab.ui.control.Button
        AreacircleapproxEditFieldLabel  matlab.ui.control.Label
        AreacircleapproxEditField       matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotpointsButton
        function PlotpointsButtonPushed(app, event)
            
            % Clear axes
            app.UIAxes.cla;
            
            % Set number of points 
            N = app.NumberofpointsEditField.Value;
            
            % Plot circle
            radius = app.RadiusEditField.Value;
            x = linspace(-radius, radius, 100);
            plot(app.UIAxes, x, sqrt(radius^2-x.^2),'r');
            hold (app.UIAxes, 'on');
            plot(app.UIAxes, x, -sqrt(radius^2-x.^2),'r');
            
            
            % Generate N random points and determine if they are inside or outside the
            % circle
            newPoints = zeros(N, 3);
            newPoints(:, 1:2) = -radius + 2*radius*rand(N, 2);
            newPoints(:, 3) = (newPoints(:, 1).^2 + newPoints(:, 2).^2) <= radius^2;
            
            % Plot points
            pointsIn = newPoints(newPoints(:,3) == 1, 1:2);
            pointsOut = newPoints(newPoints(:,3) == 0, 1:2);
            
            plot(app.UIAxes, pointsIn(:, 1), pointsIn(:, 2), 'r.', 'MarkerSize', 10);
            plot(app.UIAxes, pointsOut(:, 1), pointsOut(:, 2), 'b.', 'MarkerSize', 10);
            
            % Calculate pi
            app.PiapproxEditField.Value = 4*sum(newPoints(:,3)) / N;
            app.AreacircleapproxEditField.Value = 4*radius^2*sum(newPoints(:,3)) / N;
            xlim(app.UIAxes, [-radius radius])
            ylim(app.UIAxes, [-radius radius])
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonteCarloPiUIFigure = uifigure('Visible', 'off');
            app.MonteCarloPiUIFigure.Name = 'Monte Carlo Pi';
            app.UIAxes = uiaxes(app.MonteCarloPiUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.NumberofpointsEditFieldLabel = uilabel(app.MonteCarloPiUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.MonteCarloPiUIFigure, 'numeric');
            app.NumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsEditField.Value = 100;
            app.PiapproxEditFieldLabel = uilabel(app.MonteCarloPiUIFigure);
            app.PiapproxEditFieldLabel.Text = 'Pi approx';
            app.PiapproxEditField = uieditfield(app.MonteCarloPiUIFigure, 'numeric');
            app.PiapproxEditField.ValueDisplayFormat = '%.5f';
            app.PlotpointsButton = uibutton(app.MonteCarloPiUIFigure, 'push');
            app.PlotpointsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpointsButtonPushed, true);
            app.PlotpointsButton.Text = 'Plot points';
            app.AreacircleapproxEditFieldLabel = uilabel(app.MonteCarloPiUIFigure);
            app.AreacircleapproxEditFieldLabel.Text = 'Area circle approx';
            app.AreacircleapproxEditField = uieditfield(app.MonteCarloPiUIFigure, 'numeric');
            app.AreacircleapproxEditField.ValueDisplayFormat = '%.5f';
            app.RadiusEditFieldLabel = uilabel(app.MonteCarloPiUIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.MonteCarloPiUIFigure, 'numeric');
            app.RadiusEditField.Limits = [1 Inf];
            app.RadiusEditField.Value = 1;
            % Show the figure after all components are created
            app.MonteCarloPiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonteCarloPi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonteCarloPiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonteCarloPiUIFigure)
        end
    end
end